classdef Audio_Processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SignalOneTab                   matlab.ui.container.Tab
        PanelSignalOne_1               matlab.ui.container.Panel
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes9                        matlab.ui.control.UIAxes
        PanelSignalOne_2               matlab.ui.container.Panel
        UIAxes10                       matlab.ui.control.UIAxes
        SignalTwoTab                   matlab.ui.container.Tab
        PanelSignalTwo_1               matlab.ui.container.Panel
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        PanelSignalTwo_2               matlab.ui.container.Panel
        UIAxes7                        matlab.ui.control.UIAxes
        OutResultButtonGroup           matlab.ui.container.ButtonGroup
        SignalResultButton             matlab.ui.control.RadioButton
        SpectogramButton               matlab.ui.control.RadioButton
        AmplitudePanel                 matlab.ui.container.Panel
        AmplitudeSliderLabel           matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        AmplitudeModeDropDownLabel     matlab.ui.control.Label
        AmplitudeModeDropDown          matlab.ui.control.DropDown
        SignalsOperationsPanel         matlab.ui.container.Panel
        SelectOperationDropDownLabel   matlab.ui.control.Label
        SelectOperationDropDown        matlab.ui.control.DropDown
        OperationButton                matlab.ui.control.Button
        h_nEditFieldLabel              matlab.ui.control.Label
        h_nEditField                   matlab.ui.control.EditField
        b_kEditFieldLabel              matlab.ui.control.Label
        b_kEditField                   matlab.ui.control.EditField
        a_kEditFieldLabel              matlab.ui.control.Label
        a_kEditField                   matlab.ui.control.EditField
        SelecttypeInPanel              matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        ChirpButton                    matlab.ui.control.Button
        InvertButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        SelectAudioLabel               matlab.ui.control.Label
        SelectAudioEditField           matlab.ui.control.EditField
        PauseButton                    matlab.ui.control.Button
        AUDIOPROCESSORPROJECTONELabel  matlab.ui.control.Label
        ConverttoButtonGroup           matlab.ui.container.ButtonGroup
        StereoButton                   matlab.ui.control.RadioButton
        MonoButton                     matlab.ui.control.RadioButton
        ConvertButton                  matlab.ui.control.Button
        SignalResultPanel              matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        SignalResultPanel_2            matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data = DataAsignament; %Vars for actually play 
        Value=0;
        DataAux;
        recorder;
        player;
        FlagAmp=true;
        FlagSignal=true;
        FirstStop=0;
    end
    % Public Methods 
    methods (Access = public)
        
        %Function for GenerateGraph
        function GenerateGraph (app,flagIn)%FlagIn Flag for the type of graph to generate
        [m, n] = size(app.Data.Audio);% Dimensions of the vectors, asignament mono o stereo channel
        if n==2 && flagIn==1   
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    app.PanelSignalOne_2.Visible='off';
                    app.PanelSignalOne_1.Visible='on';
                    plot(app.UIAxes8,app.Data.Audio(:,1));
                    plot(app.UIAxes9,app.Data.Audio(:,2));
                    drawnow();
                case 'Signal Two'
                    app.PanelSignalTwo_2.Visible='off';
                    app.PanelSignalTwo_1.Visible='on';
                    plot(app.UIAxes5,app.Data.Audio(:,1));
                    plot(app.UIAxes6,app.Data.Audio(:,2));
                    drawnow();
            end            
        elseif n==1 && flagIn==1
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    app.PanelSignalOne_2.Visible='on';
                    app.PanelSignalOne_1.Visible='off';
                    plot(app.UIAxes10,app.Data.Audio());
                    drawnow();
                case 'Signal Two'
                    app.PanelSignalTwo_2.Visible='on';
                    app.PanelSignalTwo_1.Visible='off';
                    plot(app.UIAxes7,app.Data.Audio());
                    drawnow();                    
            end
        elseif  n==2 && flagIn==0
            app.SignalResultPanel_2.Visible='off';
            app.SignalResultPanel.Visible='on';
            plot(app.UIAxes3,app.Data.Audio(:,1));
            plot(app.UIAxes3_2,app.Data.Audio(:,2));
        elseif n==1 && flagIn==0
            app.SignalResultPanel_2.Visible='on';
            app.SignalResultPanel.Visible='off';
            plot(app.UIAxes4,app.Data.Audio());  
        elseif flagIn==3
            disp('estoy Quie');
            [S,F,T] = spectrogram(app.Data.Audio(:,1));
            imagesc(app.UIAxes4, F, T, log(abs(S')));
            set(app.UIAxes3,'YDir', 'normal');            
            
        end
        end

        function AsignamentData (app)
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    app.Data.Audio1=app.Data.Audio;
                    app.Data.Fs1=app.Data.Fs;
                case 'Signal Two'
                    app.Data.Audio2=app.Data.Audio;
                    app.Data.Fs2=app.Data.Fs;
                    
            end            
        end
        function ApplyGain(app,Option)
            switch Option
                case 1
                    if ~isempty(app.player)
                        if app.player.isplaying()
                            pause(app.player);
                            app.Data.Audio=app.DataAux(app.FirstStop:length(app.DataAux));
                            app.Data.Audio=app.GainEditField.Value*(app.Data.Audio);
                            app.player = audioplayer(app.Data.Audio,app.Data.Fs)
                            app.player.play();
                            
                        end
                    end
                case 2
                    if ~isempty(app.player)
                        if app.player.isplaying()
                            pause(app.player);
                            data_l=length(app.Data.Audio);
                            up = transpose(linspace(0,5,data_l));
                            [m, n] = size(app.Data.Audio);
                            if n==2                                
                                app.Data=Convert2Mono(app.Data);
                            end
                                app.Data.Audio=dot(up,app.Data.Audio,2);
                                app.player = audioplayer(app.Data.Audio,app.Data.Fs);
                                app.player.play();                            
                        end
                        
                    end
                case 3
                    if ~isempty(app.player)
                        if app.player.isplaying()
                            pause(app.player);
                            data_l=length(app.Data.Audio);
                            down=transpose(linspace(3,0,data_l));
                            [m, n] = size(app.Data.Audio);
                            if n==2                                
                                app.Data=Convert2Mono(app.Data);
                            end
                                app.Data.Audio=dot(down,app.Data.Audio,2);
                                app.player = audioplayer(app.Data.Audio,app.Data.Fs);
                                app.player.play();                            
                        end
                        
                    end
            end
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function ButtonRecord(app, event)
            switch app.Switch.Value
                case 'Mono'
                    Modo = 1
                case 'Stereo'                    
                    Modo = 2
            end
            text_Caja={ 'Frecuencia Muestreo'};
            title_caja='Grabación';
            num_lines = 1;
            defaultans = {'8000'};
            caja =inputdlg(text_Caja,title_caja,num_lines,defaultans);
            app.Data.Fs=str2num(caja{1});%frecuencia de muestreo
            app.recorder = audiorecorder(app.Data.Fs, 8, Modo);
            disp('Start speaking.')
            app.Lamp.Color=[1 0 0];
            app.recorder.record();
            while app.recorder.isrecording()
                pause(0.1);
                app.Data.Audio=app.recorder.getaudiodata();
                GenerateGraph(app,1);                
            end
            AsignamentData(app);
        end

        % Button pushed function: Button
        function ButtonOpenFolder(app, event)
            try
                [app.Data.Audio,app.Data.Fs, path] =Open_File();
                app.SelectAudioEditField.Value=path;
                GenerateGraph(app,1);
                AsignamentData(app);
            catch ME
                f = msgbox('Don´t choose file', 'Error','error');
            end
        end

        % Button pushed function: Button_3
        function ButtonPlay(app, event)
            if isempty(app.Data.Audio)
                f = msgbox('Chosse File or Record Audio', 'Error','error');                
            else
                app.FlagAmp=true;
                [app.player,app.Data]= Play_Audio(app.Data,app.TabGroup.SelectedTab.Title);
                GenerateGraph(app,0);
                app.player.play();
            end
        end

        % Callback function
        function SignalTwoTabSizeChanged(app, event)
            

        end

        % Button pushed function: ChirpButton
        function ChirpButtonPushed(app, event)
            try
                app.Data= ObtChirp
                GenerateGraph(app,1);
                AsignamentData(app);
            catch ME
               uiwait(msgbox('Don´t choose Signal','Operation Canceled','modal'));
            end 
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            try
                app.Data.Audio=flip(app.Data.Audio);
                GenerateGraph(app,0);
                AsignamentData(app);
                GenerateGraph(app,1);
            catch ME
                uiwait(msgbox('Please, select data','Operation Canceled','modal'));
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            try
                [fila,c]=size(app.Data.Audio);
                
                app.MonoButton.Value
                app.StereoButton.Value
                if app.StereoButton.Value==1 && c==1
                    disp('Convert To Stereo')
                    app.Data=Convert2Stereo(app.Data);
                    GenerateGraph(app,0);
                    AsignamentData(app);
                    %GenerateGraph(app,1);
                end
                if app.MonoButton.Value==1 && c==2
                    disp('Convert To Mono app')
                    app.Data=Convert2Mono(app.Data);
                    GenerateGraph(app,0);
                    AsignamentData(app);
                    %GenerateGraph(app,1);
                    %app.player.play();
                end
            catch ME
                f = msgbox('Please, select data', 'Error','error');
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.FlagAmp=true;
            if ~isempty(app.recorder)               
                if app.recorder.isrecording()
                    app.recorder.stop();
                    app.Lamp.Color=[0 1 0];
                end
            end        
            if ~isempty(app.player)                
                if app.player.isplaying()
                    disp("Se hizo el Stop");                                   
                    stop(app.player);
                end
            end            
        end

        % Value changed function: SelectOperationDropDown
        function SelectOperationDropDownValueChanged(app, event)
            value = app.SelectOperationDropDown.Value
            switch value
                case 'Add'
                    %app.Data= verify_frequency(app.Data_Audio1,app.Data_Audio2,app.Fs1,app.Fs2);
                    valor=1
                    app.h_nEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';
                case 'Times'
                    valor=2
                    app.h_nEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';
                case 'Rebuild'
                    valor=3 
                    app.h_nEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';               
                case 'IIR'
                    valor=4
                    app.h_nEditField.Enable='off';
                    app.b_kEditField.Enable='on';
                    app.a_kEditField.Enable='on';
                                  
                case 'FIR'
                    valor=5
                    app.h_nEditField.Enable='on';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off'; 
            end
        end

        % Size changed function: SignalTwoTab
        function SignalTwoTabSizeChanged2(app, event)
            disp('Signal Change')
        end

        % Size changed function: SignalOneTab
        function SignalOneTabSizeChanged2(app, event)
            disp('Signal Change')
        end

        % Button pushed function: OperationButton
        function OperationButtonPushed(app, event)
           Value= app.SelectOperationDropDown.Value;           
           %try
               switch Value
                   case 'Add'
                       [m,n_1]=size(app.Data.Audio1);
                       [m,n_2]=size(app.Data.Audio2);
                       if(n_1==2)
                           app.Data.Audio=app.Data.Audio1;
                           app.Data.Fs=app.Data.Fs1;
                           app.Data=Convert2Mono(app.Data);
                           app.Data.Audio1=app.Data.Audio;
                           app.Data.Fs1=app.Data.Fs;
                           size(app.Data.Audio1)
                       end
                       if(n_2==2)
                           app.Data.Audio=app.Data.Audio2;
                           app.Data.Fs=app.Data.Fs2;
                           app.Data=Convert2Mono(app.Data);
                           app.Data.Audio2=app.Data.Audio;
                           app.Data.Fs2=app.Data.Fs;
                           size(app.Data.Audio2)
                       end
                       app.Data=VerifyFrequency(app.Data);
                       size(app.Data.Audio1);
                       size(app.Data.Audio2);
                       app.Data.Audio=(app.Data.Audio1)+(app.Data.Audio2);
                       GenerateGraph(app,0);
                       AsignamentData(app);
                       
                   case 'Times'
                       [m,n]=size(app.Data.Audio);
                       if(n==2)
                           app.Data=Convert2Mono(app.Data);
                           AsignamentData(app);
                       end;
                       app.Data=VerifyFrequency(app.Data);
                       disp('Ya Lo hizo')
                       size(app.Data.Audio1)
                       size(app.Data.Audio2)
                       app.Data.Audio=dot((app.Data.Audio1),(app.Data.Audio2),2);
                       GenerateGraph(app,0);
                       AsignamentData(app);
                   case 'Rebuild' 
                       [m,n]=size(app.Data.Audio);
                       if(n==2)
                           app.Data=Convert2Mono(app.Data);
                           AsignamentData(app);
                       end
                       app.Data=Convert2Mono(app.Data);
                       AsignamentData(app);
                       app.Data=VerifyFrequency(app.Data);
                       size(app.Data.Audio1)
                       size(app.Data.Audio2)
                       app.Data=Rebuild2Stereo(app.Data);
                       GenerateGraph(app,0);
                       AsignamentData(app);                             
                   case 'IIR'
                       a_k=str2num(app.a_kEditField.Value);
                       b_k=str2num(app.b_kEditField.Value);
                       app.Data=Filter_IIR(app.Data,a_k,b_k,app.TabGroup.SelectedTab.Title);
                       GenerateGraph(app,0);
                       AsignamentData(app);                       
                   case 'FIR'
                       H=str2num(app.h_nEditField.Value);
                       app.Data=FIR_Convolution(app.Data,H,app.TabGroup.SelectedTab.Title);
                       GenerateGraph(app,0);
                       AsignamentData(app);
                       
               end
%            catch ME
%                f = msgbox('Please, select data', 'Error','error'); 
%            end
           
               
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            try
                app.GainEditField.Value=event.Value;
                if app.FlagAmp==true
                    app.DataAux=app.Data.Audio;
                    app.FirstStop=app.player.CurrentSample;
                    app.FlagAmp=false;
                else
                    app.FirstStop=app.FirstStop+app.player.CurrentSample;
                end
                ApplyGain(app,1);
            catch ME
               f = msgbox('Please, select data', 'Error','error'); 
           end    
            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            try
                value = app.GainEditField.Value;
                app.AmplitudeSlider.Value=value;
                if app.FlagAmp==true
                    app.DataAux=app.Data.Audio;
                    app.FirstStop=app.player.CurrentSample;
                    app.FlagAmp=false;
                else
                    app.FirstStop=app.FirstStop+app.player.CurrentSample;
                end
                ApplyGain(app,1);
            catch ME
                f = msgbox('Please, select data', 'Error','error');
            end
            
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title
            
            disp('Signal Change')
            switch selectedTab
                case 'Signal One'
                    if ~isempty(app.Data.Audio1)
                        disp('Signal One')
                        app.Data.Audio=app.Data.Audio1;
                    end
                case 'Signal Two'
                    if ~isempty(app.Data.Audio1)
                        disp('Signal Two')
                        app.Data.Audio=app.Data.Audio2;
                    end
                    
            end
        end

        % Value changed function: AmplitudeModeDropDown
        function AmplitudeModeDropDownValueChanged(app, event)
                value = app.AmplitudeModeDropDown.Value;
                app.GainEditField.Enable;
                app.DataAux=app.Data.Audio;
                switch value
                    case'Gain'
                        app.GainEditField.Enable='on';
                        app.AmplitudeSlider.Enable='on';
                    case 'Up Auto'
                        app.GainEditField.Enable='off';
                        app.AmplitudeSlider.Enable='off';                        
                    case 'Down Auto'
                        app.GainEditField.Enable='off';
                        app.AmplitudeSlider.Enable='off';                        
                end    
                try
                    switch value
                        case'Gain'                            
                        case 'Up Auto'
                            ApplyGain(app,2);
                        case 'Down Auto'
                            ApplyGain(app,3);
                            
                    end
                catch ME
                    f = msgbox('Please, select data', 'Error','error');
                end
            
        end

        % Selection changed function: OutResultButtonGroup
        function OutResultButtonGroupSelectionChanged(app, event)
            selectedButton = app.OutResultButtonGroup.SelectedObject.Text
            switch selectedButton
                case 'Signal Result '
                    
                case 'Spectogram'
                    disp('Antes de')
                    GenerateGraph(app,3);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SignalOneTab = uitab(app.TabGroup);
            app.SignalOneTab.SizeChangedFcn = createCallbackFcn(app, @SignalOneTabSizeChanged2, true);
            app.SignalOneTab.Title = 'Signal One';
            app.PanelSignalOne_1 = uipanel(app.SignalOneTab);
            app.PanelSignalOne_1.Visible = 'off';
            app.UIAxes8 = uiaxes(app.PanelSignalOne_1);
            title(app.UIAxes8, 'Right Channel')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.UIAxes9 = uiaxes(app.PanelSignalOne_1);
            title(app.UIAxes9, 'Left Channel')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.PanelSignalOne_2 = uipanel(app.SignalOneTab);
            app.UIAxes10 = uiaxes(app.PanelSignalOne_2);
            title(app.UIAxes10, 'Data')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.SignalTwoTab = uitab(app.TabGroup);
            app.SignalTwoTab.SizeChangedFcn = createCallbackFcn(app, @SignalTwoTabSizeChanged2, true);
            app.SignalTwoTab.Title = 'Signal Two';
            app.PanelSignalTwo_1 = uipanel(app.SignalTwoTab);
            app.UIAxes5 = uiaxes(app.PanelSignalTwo_1);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.PanelSignalTwo_1);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.PanelSignalTwo_2 = uipanel(app.SignalTwoTab);
            app.UIAxes7 = uiaxes(app.PanelSignalTwo_2);
            title(app.UIAxes7, 'Data')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.OutResultButtonGroup = uibuttongroup(app.UIFigure);
            app.OutResultButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutResultButtonGroupSelectionChanged, true);
            app.OutResultButtonGroup.Title = 'Out Result';
            app.SignalResultButton = uiradiobutton(app.OutResultButtonGroup);
            app.SignalResultButton.Text = 'Signal Result ';
            app.SignalResultButton.Value = true;
            app.SpectogramButton = uiradiobutton(app.OutResultButtonGroup);
            app.SpectogramButton.Text = 'Spectogram';
            app.AmplitudePanel = uipanel(app.UIFigure);
            app.AmplitudePanel.Title = 'Amplitude';
            app.AmplitudeSliderLabel = uilabel(app.AmplitudePanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.AmplitudePanel);
            app.AmplitudeSlider.Limits = [0 5];
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.Value = 1;
            app.GainEditFieldLabel = uilabel(app.AmplitudePanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.AmplitudePanel, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 1;
            app.AmplitudeModeDropDownLabel = uilabel(app.AmplitudePanel);
            app.AmplitudeModeDropDownLabel.Text = 'Amplitude Mode';
            app.AmplitudeModeDropDown = uidropdown(app.AmplitudePanel);
            app.AmplitudeModeDropDown.Items = {'Gain', 'Up Auto', 'Down Auto'};
            app.AmplitudeModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeModeDropDownValueChanged, true);
            app.AmplitudeModeDropDown.Value = 'Gain';
            app.SignalsOperationsPanel = uipanel(app.UIFigure);
            app.SignalsOperationsPanel.Title = 'Signals Operations';
            app.SelectOperationDropDownLabel = uilabel(app.SignalsOperationsPanel);
            app.SelectOperationDropDownLabel.Text = 'Select Operation:';
            app.SelectOperationDropDown = uidropdown(app.SignalsOperationsPanel);
            app.SelectOperationDropDown.Items = {'Add', 'Times', 'Rebuild', 'IIR', 'FIR'};
            app.SelectOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectOperationDropDownValueChanged, true);
            app.SelectOperationDropDown.Value = 'Add';
            app.OperationButton = uibutton(app.SignalsOperationsPanel, 'push');
            app.OperationButton.ButtonPushedFcn = createCallbackFcn(app, @OperationButtonPushed, true);
            app.OperationButton.Icon = 'derecho.png';
            app.OperationButton.Text = '';
            app.h_nEditFieldLabel = uilabel(app.SignalsOperationsPanel);
            app.h_nEditFieldLabel.Text = 'h_n';
            app.h_nEditField = uieditfield(app.SignalsOperationsPanel, 'text');
            app.h_nEditField.Enable = 'off';
            app.h_nEditField.Value = '0.0000    0.0002    0.0011    0.0032    0.0019   -0.0128   -0.0345   -0.0138    0.1027    0.2732    0.3576    0.2732    0.1027   -0.0138   -0.0345   -0.0128    0.0019    0.0032    0.0011    0.0002';
            app.b_kEditFieldLabel = uilabel(app.SignalsOperationsPanel);
            app.b_kEditFieldLabel.Text = 'b_k';
            app.b_kEditField = uieditfield(app.SignalsOperationsPanel, 'text');
            app.b_kEditField.Enable = 'off';
            app.b_kEditField.Value = '0.006933196 0.034665981 0.069331961 0.069331961 0.034665981 0.006933196';
            app.a_kEditFieldLabel = uilabel(app.SignalsOperationsPanel);
            app.a_kEditFieldLabel.Text = 'a_k';
            app.a_kEditField = uieditfield(app.SignalsOperationsPanel, 'text');
            app.a_kEditField.Enable = 'off';
            app.a_kEditField.Value = '1 -1.975901616 2.013473026 -1.102617978 0.327618334 -0.04070949';
            app.SelecttypeInPanel = uipanel(app.UIFigure);
            app.SelecttypeInPanel.Title = 'Select type In';
            app.Button = uibutton(app.SelecttypeInPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenFolder, true);
            app.Button.Icon = 'icons8-música-64.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.SelecttypeInPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRecord, true);
            app.Button_2.Icon = 'icons8-micrófono-64(1).png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.SelecttypeInPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlay, true);
            app.Button_3.Icon = 'icons8-sony-vegas-64.png';
            app.Button_3.Text = '';
            app.Switch = uiswitch(app.SelecttypeInPanel, 'slider');
            app.Switch.Items = {'Mono', 'Stereo'};
            app.Switch.Value = 'Mono';
            app.ChirpButton = uibutton(app.SelecttypeInPanel, 'push');
            app.ChirpButton.ButtonPushedFcn = createCallbackFcn(app, @ChirpButtonPushed, true);
            app.ChirpButton.Icon = 'icons8-onda-sonora-64.png';
            app.ChirpButton.Text = 'Chirp';
            app.InvertButton = uibutton(app.SelecttypeInPanel, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Icon = 'flecha-hacia-la-izquierda.png';
            app.InvertButton.Text = 'Invert';
            app.Lamp = uilamp(app.SelecttypeInPanel);
            app.SelectAudioLabel = uilabel(app.SelecttypeInPanel);
            app.SelectAudioLabel.Text = 'Select Audio:';
            app.SelectAudioEditField = uieditfield(app.SelecttypeInPanel, 'text');
            app.PauseButton = uibutton(app.SelecttypeInPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'detener.png';
            app.PauseButton.Text = '';
            app.AUDIOPROCESSORPROJECTONELabel = uilabel(app.UIFigure);
            app.AUDIOPROCESSORPROJECTONELabel.Text = 'AUDIO PROCESSOR PROJECT ONE';
            app.ConverttoButtonGroup = uibuttongroup(app.UIFigure);
            app.ConverttoButtonGroup.Title = 'Convert to';
            app.StereoButton = uiradiobutton(app.ConverttoButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.StereoButton.Value = true;
            app.MonoButton = uiradiobutton(app.ConverttoButtonGroup);
            app.MonoButton.Text = 'Mono';
            app.ConvertButton = uibutton(app.ConverttoButtonGroup, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Icon = 'derecho.png';
            app.ConvertButton.Text = '';
            app.SignalResultPanel = uipanel(app.UIFigure);
            app.SignalResultPanel.Title = 'Signal Result';
            app.UIAxes3 = uiaxes(app.SignalResultPanel);
            title(app.UIAxes3, 'Right Channel')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.SignalResultPanel);
            title(app.UIAxes3_2, 'Left Channel')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.SignalResultPanel_2 = uipanel(app.UIFigure);
            app.SignalResultPanel_2.Title = 'Signal Result';
            app.UIAxes4 = uiaxes(app.SignalResultPanel_2);
            title(app.UIAxes4, 'Data')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Processor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end