classdef Procesamiento_Audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        EntradasTab                matlab.ui.container.Tab
        PlayButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        ProcesadordeAudioLabel     matlab.ui.control.Label
        GrabarLampLabel            matlab.ui.control.Label
        GrabarLamp                 matlab.ui.control.Lamp
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        EntradaDropDownLabel       matlab.ui.control.Label
        EntradaDropDown            matlab.ui.control.DropDown
        CargarButton_3             matlab.ui.control.Button
        GrabarButton_5             matlab.ui.control.Button
        ChirpButton_3              matlab.ui.control.Button
        SalidaTab                  matlab.ui.container.Tab
        OperacinDropDownLabel      matlab.ui.control.Label
        OperacinDropDown           matlab.ui.control.DropDown
        Spinner                    matlab.ui.control.Spinner
        AmplitudKnob               matlab.ui.control.Knob
        GanaciaLabel               matlab.ui.control.Label
        ConversinButtonGroup       matlab.ui.container.ButtonGroup
        MonoButton_3               matlab.ui.control.RadioButton
        StereoButton_3             matlab.ui.control.RadioButton
        InvertirButton             matlab.ui.control.Button
        StopButton_2               matlab.ui.control.Button
        Out_plot                   matlab.ui.control.UIAxes
        SeleccioneSealButtonGroup  matlab.ui.container.ButtonGroup
        Seal1Button                matlab.ui.control.RadioButton
        Seal2Button                matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Senal='Señal 01';  
        flagSignal="True";
        senalop='True';        
        flagChanSig_1="Fals"
        flagChanSig_2="Fals"
        reproductor;
        amplitud=0;
        Modo_1;
        Modo_2;
        
        selectedButton_Mono;
        
        %Data_audio;
        Data_01;
        Data_02;
        %Fs;
        fs_01;
        fs_02;


    end
    
    methods (Access = public)
        function GenerarGrafica(app,Modo,Data_audio)
            plot(app.UIAxes2_2,0);
            plot(app.UIAxes2,0);
            %clear app.UIAxes2 app.UIAxes2_2
            if Modo==1
                app.UIAxes2_2.Visible = 'off';
                plot(app.UIAxes2,Data_audio(:,1));
                % plot(app.UIAxes2_2,Data_audio(:,2))
 
            elseif Modo==2
                app.UIAxes2_2.Visible = 'on';
                plot(app.UIAxes2,Data_audio(:,1));
                plot(app.UIAxes2_2,Data_audio(:,2))
            elseif Modo==3
                stem(app.UIAxes2_2,Data_audio(:,1));
                plot(app.UIAxes2,Data_audio(:,1));
                
            end
        end
        function  ObtGrabacion(app,flagSignal)
            clear app.Data_audio app.UIAxes2_2 %limpio Variables
                    
                    %                    Solicitar Modo de Grabación
                    answer_Modo = questdlg('¿En que modo desea grabar?', ...
                       	'Modo de Grabación', ...
                       	'Stereo','Mono','Mono');
                    
                    switch answer_Modo
                        case 'Mono'
                            disp([answer_Modo ' coming right up.'])
                            Modo = 1;
                        case 'Stereo'
                            disp([answer_Modo ' coming right up.'])
                            Modo = 2;
                            
                    end
                    %                    Solicitar Duración Y FS
                    text_Caja={ 'Segundos grabación:','Frecuencia Muestreo'};
                    title_caja='Grabación';
                    num_lines = 1;
                    defaultans = {'5','44100'};
                    caja =inputdlg(text_Caja,title_caja,num_lines,defaultans);
                    
                    T_record=str2num(caja{1});%duración
                    Fs=str2num(caja{2});%frecuencia de muestreo
                    
                    
                    %Grabar
                    app.GrabarLamp.Color = [0 1 0];
                    grabar= audiorecorder(Fs,8,Modo);
                    recordblocking(grabar,T_record);
                    app.GrabarLamp.Color = [1 0 0];
                    
                    Data_audio = getaudiodata(grabar,"uint8");%Variable Global de Grabacion
                    app.UIAxes2_2.Visible = 'off';
                    plot(app.UIAxes2,Data_audio(:,1));
                    plot(app.UIAxes2_2,0)
                    
                    if flagSignal=="True"
                        
                        app.Data_01= Data_audio;
                        app.fs_01=Fs;
                        app.Modo_1=Modo;
                        app.flagSignal="True"
                        app.flagChanSig_1="True"
                       
                    elseif flagSignal=="Fals" 
                        app.Data_02= Data_audio;
                        app.Modo_2=Modo;
                        app.fs_02=Fs;
                        app.flagSignal="Fals"; 
                        app.flagChanSig_2="True"
                    end
                    GenerarGrafica(app,Modo,Data_audio);


        end
        
        function ObtArchivo(app,flagSignal)
        
            plot(app.UIAxes2_2,0);
            plot(app.UIAxes2,0);
            clear app.Data_audio
            [archivo,ruta]=uigetfile('*.mp3','Cargar Audio-MP3');
            if archivo==0
                return;
            else
                Dir_archivo=strcat(ruta,archivo);
                
                A = importdata(Dir_archivo);
                
                [Data_audio, fs]=audioread(Dir_archivo);
                audioinfo(Dir_archivo);                
            end
            GenerarGrafica(app,2,Data_audio);
%             app.UIAxes2_2.Visible = 'on';
%             plot(app.UIAxes2,Data_audio(:,1))
%             plot(app.UIAxes2_2,Data_audio(:,2))
%             
            if flagSignal=="True"
                
                app.Data_01= Data_audio;
                app.fs_01=fs
                app.flagSignal="True"
                app.flagChanSig_1="True";
                
            elseif flagSignal=="Fals"
                app.Data_02= Data_audio;
                app.fs_02=fs;
                app.flagSignal="Fals";
                app.flagChanSig_2="True";
            end
        end
        function ObtChirp(app,flagSignal)
            Parameters = {'InitialFrequency:',...
                'TargetFrequency:', ...
                'TargetTime:', ...
                'SweepTime:', ...
                'SampleRate:', ...
                'SamplesPerFrame:'...,
                'Amplitud:'
                };
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0','5','10','50','20','100','1'};
            answer=definput;
            answer = inputdlg(Parameters,dlgtitle,dims,definput);
            %                     if app.flagChirp == 'True'
            %                         answer = inputdlg(Parameters,dlgtitle,dims,definput);
            %                         app.flagChirp='False';
            %                     end
            
            InitFreq=str2num(answer{1});
            TargetFreq=str2num(answer{2});
            TargetTime=str2num(answer{3});
            SweepTime=str2num(answer{4});
            SampleRate=str2num(answer{5});
            SamplesPerFrame=str2num(answer{6});
            Amplitude=str2num(answer{7});
            Amplitude=app.amplitud;
            hchirp = dsp.Chirp( ...
                'InitialFrequency',InitFreq,...
                'TargetFrequency',TargetFreq, ...
                'TargetTime',TargetTime, ...
                'SweepTime', SweepTime, ...
                'SampleRate',SampleRate, ...
                'SamplesPerFrame',SamplesPerFrame);
            
            %                     chirp = Amplitude*(step(hchirp))';
            %                     Amplitude
            app.amplitud
            if app.amplitud==0
                app.amplitud=1
            end
            app.amplitud
            
            t = linspace(0, 4.8, 4.8E+4);
            f0 =   200;
            f1 = 10000;
            Fs = 1/mean(diff(t));
            x = hchirp(t,f0,t(end),f1);
            Data_audio = x
            hchirp = app.amplitud*(step(hchirp))';
%             Data_audio = chirp.'
            
            app.UIAxes2_2.Visible = 'on';
            %                     app.chirpData =circshift(app.chirpData,Shift);
            GenerarGrafica(app,3,Data_audio);
%             stem(app.UIAxes2_2,chirp);
%             plot(app.UIAxes2,chirp);
            Data_audio
            if flagSignal=="True"
                
                app.Data_01= Data_audio;
%                 app.fs_01=fs
                app.flagSignal="True"
                app.flagChanSig_1="True";
                
            elseif flagSignal=="Fals"
                app.Data_02= Data_audio;
%                 app.fs_02=fs;
                app.flagSignal="Fals";
                app.flagChanSig_2="True";
            end
        end
        
        function Convolucion(app)
            Parameters = {'Ingrese h(n):',...
                };
            dlgtitle = 'Convolucion';
            dims = [1 35];
            definput = {'0 5 10 50 20 100 1'};            
            answer = inputdlg(Parameters,dlgtitle,dims,definput);            
            h_n=str2num(answer{1});
            
            if app.senalop== "True"
                
                x_n=app.Data_01;
                
            elseif app.senalop== "Fals"
                x_n=app.Data_02;
            end                    
        end
           
        function  EcuDiferencias(app)
            Parameters = {'Ingrese h(n):',...
                'Ingrese a_k:',...
                'Ingrese b_k'};
            dlgtitle = 'Ecuaciones de Diferencia';
            dims = [1 35];
            definput = {'0 5 10 50 20 100 1','0 5 10 50 20 100 1','0 5 10 50 20 100 1'};            
            answer = inputdlg(Parameters,dlgtitle,dims,definput);            
            h_n=str2num(answer{1});
            a_k=str2num(answer{2});
            b_k=str2num(answer{3});
            
            if app.senalop== "True"
                x_n=app.Data_01;
            elseif app.senalop== "Fals"
                x_n=app.Data_02;
            end   
            
        end
        
        function Interpolacion(app,Fs1,Fs2,x1,x2)
            if Fs1 ~= Fs2
                ts1=(1/Fs1);
                ts2=(1/Fs2);
                t1=0:ts1:((length(x1)-1)*ts1);
                t1(end)
                length(t1)
                t2=0:ts2:((length(x2)-1)*ts2);
                t2(end)
                length(t2)
                if Fs1>Fs2
                    x_M=interp1(t1,x1,x2, 'spline');
                    length(x_M)
                    length(t1)
                else
                    x_M=interp1(t2,x2,x1, 'spline');
                    length(x_M)
                    length(t2)
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
         if app.flagSignal=="True"
            
            app.reproductor = audioplayer(app.Data_01,app.fs_01);
            play(app.reproductor);
         else             
            app.reproductor = audioplayer(app.Data_02,app.fs_02);
            play(app.reproductor);
         end
            
            %             stop(reproductor)
        end

        % Callback function
        function TipodeentradaButtonGroupSelectionChanged(app, event)
            %no se APLICA
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.reproductor)
        end

        % Callback function
        function InvertirButton_2Pushed(app, event)
            %no se APLICA
        end

        % Callback function
        function AmplitudKnobValueChanged(app, event)
            value_amplitud= app.AmplitudKnob.Value;
            
            % % %          app.amplitud = value_amplitud;
            %
            %             app.AmplitudeEditField.Value=app.AmplitudeSelect.Value;
            %             Amplitude=app.AmplitudeSelect.Value;
            % app.AmplitudKnob.Value=Amplitude;
            
        end

        % Callback function
        function SpinnerValueChanged(app, event)
            Changevalue=event.Value;
            app.amplitud=Changevalue;
            app.AmplitudKnob.Value=app.amplitud;
            
        end

        % Callback function
        function ModoButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.ConversinButtonGroup.SelectedObject;
            %             app.selectedButton_Mono = selectedButton.Text
            %               if app.selectedButton_Mono =='Stereo'
            %                     app.Data_audio;
            %                     plot(app.UIAxes2,app.Data_audio(:,1));
            %                     plot(app.UIAxes2_2,app.Data_audio(:,2))
            %               elseif app.selectedButton_Mono=='Mono'
            %                     app.Data_audio;
            %                     plot(app.UIAxes2,app.Data_audio(:,1));
            %               end
        end

        % Callback function
        function SpinnerValueChanging(app, event)
            %no se APLICA
        end

        % Callback function
        function AmplitudKnobValueChanging(app, event)
            %no se APLICA
        end

        % Button pushed function: CargarButton_3
        function CargarButton_3Pushed(app, event)
            
            switch app.Senal
                case 'Señal 01'
                    ObtArchivo(app,"True");
                case 'Señal 02'
                    ObtArchivo(app,"Fals");
            end
            
            
        end

        % Button pushed function: GrabarButton_5
        function GrabarButton_5Pushed(app, event)
            switch app.Senal
                case 'Señal 01'
                   app.flagSignal="True";
                   ObtGrabacion(app,app.flagSignal);                     
                case 'Señal 02'
                    app.flagSignal="Fals";
                    ObtGrabacion(app,app.flagSignal); 
                    
            end
            
        end

        % Button pushed function: ChirpButton_3
        function ChirpButton_3Pushed(app, event)
%            switch app.Senal
%                 case 'Señal 01'
%                    app.flagSignal="True";
%                    ObtChirp(app,app.flagSignal);                     
%                 case 'Señal 02'
%                     app.flagSignal="Fals";
%                     ObtChirp(app,app.flagSignal); 
%                     
%             end
        %Signal=Create_Function(3)
        end

        % Value changed function: Spinner
        function SpinnerValueChanged2(app, event)
            %value = app.Spinner.Value;
            changingValue = event.Value;
            %app.amplitud=changingValue;
            %app.AmplitudKnob.Value=app.amplitud;
        end

        % Value changing function: AmplitudKnob
        function AmplitudKnobValueChanging2(app, event)
            %changingValue = event.Value;
            changingValue = event.Value;
            app.amplitud=changingValue;
            app.Spinner.Value=app.amplitud;
        end

        % Value changed function: EntradaDropDown
        function EntradaDropDownValueChanged(app, event)
            value_senal = app.EntradaDropDown.Value
            app.Senal=value_senal
            if app.flagChanSig_1 == "True" && value_senal == "Señal 01"
                disp("Entro_1")
                GenerarGrafica(app,app.Modo_1,app.Data_01);
            elseif app.flagChanSig_2 == "True" && value_senal == "Señal 02"
                GenerarGrafica(app,app.Modo_2,app.Data_02);
                disp("Entro_2")
            end
        end

        % Value changed function: OperacinDropDown
        function OperacinDropDownValueChanged(app, event)
            value_ope= app.OperacinDropDown.Value
            
            switch value_ope
                case 'FIR (Convolucion)'
                    Convolucion(app); 
                    
                case 'IIR(Ecuación diferencia)'
                    EcuDiferencias(app)
                    
                case 'Suma'
                    Interpolacion(app,app.fs_01,app.fs_02,app.Data_01,app.Data_02);
                    
                case 'Multiplicación'
                    Interpolacion(app,app.fs_01,app.fs_02,app.Data_01,app.Data_02);
                case 'Amplitud Creciente'
                case 'Amplitud Decrenciente'

            end
        end

        % Button pushed function: InvertirButton
        function InvertirButtonPushed(app, event)
            B1 = flip(app.Data_audio(:,1));
            B2 = flip(app.Data_audio(:,2));
            B =[B1,B2];
            app.reproductor = audioplayer(B,app.fs);
            play(app.reproductor);
        end

        % Selection changed function: SeleccioneSealButtonGroup
        function SeleccioneSealButtonGroupSelectionChanged(app, event)
            selectedButton = app.SeleccioneSealButtonGroup.SelectedObject
            selectedButton.Text
            switch selectedButton.Text
                case 'Señal 1'
                    app.senalop='True'
                case'Señal 2'
                    app.senalop='Fals'
            end
            app.senalop
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntradasTab = uitab(app.TabGroup);
            app.EntradasTab.Title = 'Entradas';
            app.PlayButton = uibutton(app.EntradasTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play_ico.png';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.EntradasTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop_ico.png';
            app.StopButton.Text = 'Stop';
            app.ProcesadordeAudioLabel = uilabel(app.EntradasTab);
            app.ProcesadordeAudioLabel.Text = 'Procesador de Audio ';
            app.GrabarLampLabel = uilabel(app.EntradasTab);
            app.GrabarLampLabel.Text = 'Grabar';
            app.GrabarLamp = uilamp(app.EntradasTab);
            app.UIAxes2 = uiaxes(app.EntradasTab);
            title(app.UIAxes2, 'Canal Derecho')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.EntradasTab);
            title(app.UIAxes2_2, 'Canal Izquierdo')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.EntradaDropDownLabel = uilabel(app.EntradasTab);
            app.EntradaDropDownLabel.Text = ' Entrada';
            app.EntradaDropDown = uidropdown(app.EntradasTab);
            app.EntradaDropDown.Items = {'Señal 01', 'Señal 02'};
            app.EntradaDropDown.ValueChangedFcn = createCallbackFcn(app, @EntradaDropDownValueChanged, true);
            app.EntradaDropDown.Value = 'Señal 01';
            app.CargarButton_3 = uibutton(app.EntradasTab, 'push');
            app.CargarButton_3.ButtonPushedFcn = createCallbackFcn(app, @CargarButton_3Pushed, true);
            app.CargarButton_3.Icon = 'cargar_ico.png';
            app.CargarButton_3.Text = 'Cargar';
            app.GrabarButton_5 = uibutton(app.EntradasTab, 'push');
            app.GrabarButton_5.ButtonPushedFcn = createCallbackFcn(app, @GrabarButton_5Pushed, true);
            app.GrabarButton_5.Icon = 'grabar_ico.png';
            app.GrabarButton_5.Text = 'Grabar';
            app.ChirpButton_3 = uibutton(app.EntradasTab, 'push');
            app.ChirpButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChirpButton_3Pushed, true);
            app.ChirpButton_3.Icon = 'onda_ico.png';
            app.ChirpButton_3.Text = 'Chirp';
            app.SalidaTab = uitab(app.TabGroup);
            app.SalidaTab.Title = 'Salida';
            app.OperacinDropDownLabel = uilabel(app.SalidaTab);
            app.OperacinDropDownLabel.Text = 'Operación';
            app.OperacinDropDown = uidropdown(app.SalidaTab);
            app.OperacinDropDown.Items = {'Ganancia', 'FIR (Convolucion)', 'IIR(Ecuación diferencia)', 'Suma', 'Multiplicación', 'Amplitud Creciente', 'Amplitud Decrenciente', ''};
            app.OperacinDropDown.ValueChangedFcn = createCallbackFcn(app, @OperacinDropDownValueChanged, true);
            app.OperacinDropDown.Value = 'Ganancia';
            app.Spinner = uispinner(app.SalidaTab);
            app.Spinner.Limits = [0 100];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged2, true);
            app.AmplitudKnob = uiknob(app.SalidaTab, 'continuous');
            app.AmplitudKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudKnobValueChanging2, true);
            app.GanaciaLabel = uilabel(app.SalidaTab);
            app.GanaciaLabel.Text = 'Ganacia';
            app.ConversinButtonGroup = uibuttongroup(app.SalidaTab);
            app.ConversinButtonGroup.Title = 'Conversión';
            app.MonoButton_3 = uiradiobutton(app.ConversinButtonGroup);
            app.MonoButton_3.Text = 'Mono';
            app.MonoButton_3.Value = true;
            app.StereoButton_3 = uiradiobutton(app.ConversinButtonGroup);
            app.StereoButton_3.Text = 'Stereo';
            app.InvertirButton = uibutton(app.SalidaTab, 'push');
            app.InvertirButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirButtonPushed, true);
            app.InvertirButton.Icon = 'invertir_ico.png';
            app.InvertirButton.Text = 'Invertir';
            app.StopButton_2 = uibutton(app.SalidaTab, 'push');
            app.StopButton_2.Icon = 'stop_ico.png';
            app.StopButton_2.Text = 'Stop';
            app.Out_plot = uiaxes(app.SalidaTab);
            title(app.Out_plot, 'Salida')
            xlabel(app.Out_plot, 'X')
            ylabel(app.Out_plot, 'Y')
            app.SeleccioneSealButtonGroup = uibuttongroup(app.SalidaTab);
            app.SeleccioneSealButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccioneSealButtonGroupSelectionChanged, true);
            app.SeleccioneSealButtonGroup.Title = 'Seleccione Señal';
            app.Seal1Button = uiradiobutton(app.SeleccioneSealButtonGroup);
            app.Seal1Button.Text = 'Señal 1';
            app.Seal1Button.Value = true;
            app.Seal2Button = uiradiobutton(app.SeleccioneSealButtonGroup);
            app.Seal2Button.Text = 'Señal 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Procesamiento_Audio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end